classdef powersim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        RunSimulationButton    matlab.ui.control.Button
        HeadwindmsSlider       matlab.ui.control.Slider
        HeadwindmsSliderLabel  matlab.ui.control.Label
        InclinedegSlider       matlab.ui.control.Slider
        InclinedegSliderLabel  matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        DynamicsTab            matlab.ui.container.Tab
        UIAxes2                matlab.ui.control.UIAxes
        CurrentTab             matlab.ui.container.Tab
        UIAxes2_2              matlab.ui.control.UIAxes
        PowerTab               matlab.ui.container.Tab
        UIAxes2_3              matlab.ui.control.UIAxes
        VoltageTab             matlab.ui.container.Tab
        UIAxes2_4              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Time
        Velocity
        Current
        Voltage
        Power
    end
    
    methods (Access = private)
        
        function simulate(app)
            simOut = sim("wheelmodsimscape.slx",'CaptureErrors','on');
            app.Time = simOut.get('tout');
            app.Velocity = simOut.get('yout').get('Velocity').Values.Data;
            app.Current = simOut.get('yout').get('Current').Values.Data; 
            app.Voltage = simOut.get('yout').get('Voltage').Values.Data;
            app.Power = simOut.get('yout').get('Power').Values.Data;
        end
        
        function resetGraph(app)
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
            cla(app.UIAxes2_4)
            plot(app.UIAxes2,app.Time,squeeze(app.Velocity));
            plot(app.UIAxes2_2,app.Time,squeeze(app.Current));
            plot(app.UIAxes2_4,app.Time,squeeze(app.Voltage));
            plot(app.UIAxes2_3,app.Time,squeeze(app.Power));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InclinedegSlider
        function InclinedegSliderValueChanged(app, event)
            value = app.InclinedegSlider.Value;
            assignin("base","angleT",value);
        end

        % Value changed function: HeadwindmsSlider
        function HeadwindmsSliderValueChanged(app, event)
            value = app.HeadwindmsSlider.Value;
            assignin("base","windSpeed",value);
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.simulate()
            app.resetGraph()
        end

        % Button down function: UIAxes2_4
        function UIAxes2_4ButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {222, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InclinedegSliderLabel = uilabel(app.LeftPanel);
            app.InclinedegSliderLabel.Text = 'Incline (deg)';
            app.InclinedegSlider = uislider(app.LeftPanel);
            app.InclinedegSlider.Limits = [0 40];
            app.InclinedegSlider.MajorTicks = [0 10 20 30 40];
            app.InclinedegSlider.ValueChangedFcn = createCallbackFcn(app, @InclinedegSliderValueChanged, true);
            app.InclinedegSlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40];
            app.HeadwindmsSliderLabel = uilabel(app.LeftPanel);
            app.HeadwindmsSliderLabel.Text = 'Head wind (m/s)';
            app.HeadwindmsSlider = uislider(app.LeftPanel);
            app.HeadwindmsSlider.Limits = [0 20];
            app.HeadwindmsSlider.MajorTicks = [0 5 10 15 20];
            app.HeadwindmsSlider.ValueChangedFcn = createCallbackFcn(app, @HeadwindmsSliderValueChanged, true);
            app.HeadwindmsSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.RunSimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DynamicsTab = uitab(app.TabGroup);
            app.DynamicsTab.Title = 'Dynamics';
            app.UIAxes2 = uiaxes(app.DynamicsTab);
            title(app.UIAxes2, 'Dynamics')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Velocity (m/s)')
            zlabel(app.UIAxes2, 'Z')
            app.CurrentTab = uitab(app.TabGroup);
            app.CurrentTab.Title = 'Current';
            app.UIAxes2_2 = uiaxes(app.CurrentTab);
            title(app.UIAxes2_2, 'Current')
            xlabel(app.UIAxes2_2, 'Time(s)')
            ylabel(app.UIAxes2_2, 'Current(A)')
            zlabel(app.UIAxes2_2, 'Z')
            app.PowerTab = uitab(app.TabGroup);
            app.PowerTab.Title = 'Power';
            app.UIAxes2_3 = uiaxes(app.PowerTab);
            title(app.UIAxes2_3, 'Power')
            xlabel(app.UIAxes2_3, 'Time(s)')
            ylabel(app.UIAxes2_3, 'Power(W)')
            zlabel(app.UIAxes2_3, 'Z')
            app.VoltageTab = uitab(app.TabGroup);
            app.VoltageTab.Title = 'Voltage';
            app.UIAxes2_4 = uiaxes(app.VoltageTab);
            title(app.UIAxes2_4, 'Voltage')
            xlabel(app.UIAxes2_4, 'Time(s)')
            ylabel(app.UIAxes2_4, 'Voltage(V)')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes2_4ButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = powersim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end